classdef UI_Loop < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        AmplitudeofSecondStimulusSlider  matlab.ui.control.Slider
        AmplitudeofSecondStimulusSliderLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        NextTrialButton    matlab.ui.control.Button
        ReplayTrialButton  matlab.ui.control.Button
        TrialNumberLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)  
        function [sliderValue,next,quit] = getStatus(app)
            sliderValue = app.AmplitudeofSecondStimulusSlider.Value;
            next = false;
            quit = false;
            if app.NextTrialButton.BackgroundColor(2) == 1
                quit = true;
                next = true;
            end
            if app.ReplayTrialButton.BackgroundColor(2) == 1
                quit = true;
                next = false;
            end
            if quit
                delete(app.UIFigure)
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, slider_pos, trial_num)
            app.AmplitudeofSecondStimulusSlider.Value = slider_pos;
            app.UIFigure.Position = [2100 300 800 600];
            app.TrialNumberLabel.Text = strcat("Trial Number: ",num2str(trial_num),"/20");
        end

        % Button pushed function: ReplayTrialButton
        function ReplayTrialButtonPushed(app, event)
            app.ReplayTrialButton.BackgroundColor = [0 1 0];
        end

        % Button pushed function: NextTrialButton
        function NextTrialButtonPushed(app, event)
            app.NextTrialButton.BackgroundColor = [0 1 0];
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {632, 632};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {338, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AmplitudeofSecondStimulusSliderLabel = uilabel(app.LeftPanel);
            app.AmplitudeofSecondStimulusSliderLabel.Text = 'Amplitude of Second Stimulus ';
            app.AmplitudeofSecondStimulusSlider = uislider(app.LeftPanel);
            app.AmplitudeofSecondStimulusSlider.Limits = [-3 3];
            app.AmplitudeofSecondStimulusSlider.MajorTicks = [-3 -2 -1 0 1 2 3];
            app.AmplitudeofSecondStimulusSlider.MajorTickLabels = {''};
            app.AmplitudeofSecondStimulusSlider.MinorTicks = [];
            app.RightPanel = uipanel(app.GridLayout);
            app.TrialNumberLabel = uilabel(app.RightPanel);
            app.TrialNumberLabel.Text = 'Trial Number: ';
            app.ReplayTrialButton = uibutton(app.RightPanel, 'push');
            app.ReplayTrialButton.ButtonPushedFcn = createCallbackFcn(app, @ReplayTrialButtonPushed, true);
            app.ReplayTrialButton.Text = {'Replay'; 'Trial'};
            app.NextTrialButton = uibutton(app.RightPanel, 'push');
            app.NextTrialButton.ButtonPushedFcn = createCallbackFcn(app, @NextTrialButtonPushed, true);
            app.NextTrialButton.Text = {'Next'; 'Trial'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UI_Loop(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end